classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        InfoLabel           matlab.ui.control.Label
        d3Label             matlab.ui.control.Label
        d2Label             matlab.ui.control.Label
        d1Label             matlab.ui.control.Label
        TrainModelButton    matlab.ui.control.Button
        ModelStatusLabel    matlab.ui.control.Label
        EstadoLabel         matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        IndicadorLamp       matlab.ui.control.Lamp
        IndicadorLampLabel  matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      
        % Parámetros del sistema
        Fs = 8;                % 8 muestras por minuto como en el paper
        ventanaSize = 8;       % tamaño de ventana (2^3 = 8)
        nVentanas = 10;        % número de ventanas para referencia
        
        % Modelo de referencia (Fase Offline)
        modeloEntrenado = false;
        refVector              % Vector de referencia (coeficientes DWT)
        refMean                % Media de distancias euclidianas
        refSigma               % Desviación estándar
        UCL                    % Upper Control Limit (media + 3*sigma)
        LCL                    % Lower Control Limit (media - 3*sigma)
        
        % Datos de entrenamiento offline
        datosOffline = [];     % Datos para entrenamiento del modelo
        
        % Monitoreo online
        timerObj               % Timer para adquisición
        isRunning = false;     % Estado del sistema
        bufferOnline = [];     % Buffer para ventana actual
        sampleCount = 0;       % Contador de muestras
        
        % Historial para visualización
        distancias = [];       % Historial de distancias
        tiempos = [];          % Timestamps
        senalCompleta = [];    % Señal completa para visualización
        
        % Estadísticas de detección
        anomaliasDetectadas = 0;
        muestrasAnalizadas = 0;

    end
    
    properties (Access = public)
       
    end
    
    methods (Access = private)

        function generarDatosOffline(app)
            % Genera datos sintéticos de entrenamiento simulando sensor normal
            fprintf('=== FASE OFFLINE: Generando datos de entrenamiento ===\n');
            
            % Generar señal de humedad normal (50% ± 20%)
            n_muestras = app.ventanaSize * app.nVentanas * 2; % Suficientes datos
            t = (0:n_muestras-1) / app.Fs;
            
            % Simular sensor de humedad con comportamiento normal
            humedad_base = 50; % Humedad base 50%
            variacion_normal = 15 * sin(2*pi*0.05*t) + 2*randn(1,n_muestras);
            app.datosOffline = humedad_base + variacion_normal;
            
            % Limitar al rango del sensor [0-100%]
            app.datosOffline = max(0, min(100, app.datosOffline));
            
            fprintf('Datos offline generados: %d muestras\n', length(app.datosOffline));
            
            % Visualizar datos de entrenamiento
            plot(app.UIAxes, app.datosOffline, 'b-', 'LineWidth', 1.5);
            title(app.UIAxes, 'Datos de Entrenamiento (Fase Offline)');
            xlabel(app.UIAxes, 'Muestras');
            ylabel(app.UIAxes, 'Humedad (%)');
            ylim(app.UIAxes, [0 100]);
            grid(app.UIAxes, 'on');
        end %end general datos 

        function entrenarModelo(app)
            % FASE OFFLINE: Construye el modelo de referencia según el paper
            
            if isempty(app.datosOffline) || length(app.datosOffline) < app.ventanaSize * app.nVentanas
                app.InfoLabel.Text = 'Error: Datos insuficientes para entrenamiento';
                app.InfoLabel.FontColor = [1 0 0];
                return;
            end
            
            
            fprintf('\n=== CONSTRUCCIÓN DEL MODELO DE REFERENCIA (MÉTODO PROMEDIADO) ===\n');
            
            %  Extraer los coeficientes DWT de TODAS las ventanas de entrenamiento
            % Se crea una matriz para guardar los coeficientes de cada ventana en una fila
            coeficientes_todas_ventanas = [];
            for i = 1:app.nVentanas
                inicio = (i-1) * app.ventanaSize + 1;
                fin = i * app.ventanaSize;
                ventana_i = app.datosOffline(inicio:fin);
                
                % Se aplica la DWT y se guardan los coeficientes en la matriz
                [c_i, ~] = wavedec(ventana_i, 3, 'haar');
                coeficientes_todas_ventanas(i, :) = c_i; 
            end

            % Crear el vector de referencia promediando los coeficientes de todas las ventanas
            % 'mean(..., 1)' promedia cada columna, creando un único vector representativo.
            app.refVector = mean(coeficientes_todas_ventanas, 1);
            fprintf('Vector de referencia promediado creado: %d coeficientes\n', length(app.refVector));

            %  Calcular las distancias de cada ventana de entrenamiento contra el PROMEDIO
            % Esto nos da una idea de la variabilidad normal del sistema.
            distancias = [];
            for i = 1:app.nVentanas
                % Se calcula la distancia euclidiana de los coeficientes de la ventana 'i'
                % con respecto al vector de referencia promediado.
                dist = norm(coeficientes_todas_ventanas(i, :) - app.refVector);
                distancias = [distancias, dist];
                fprintf('Ventana %d: Distancia al promedio = %.4f\n', i, dist);
            end
            

            % Calcular límites de control (Ecuación 15: media ± 3*sigma)
           
            app.refMean = mean(distancias);
            app.refSigma = std(distancias);
            app.UCL = app.refMean + 2.5 * app.refSigma;
            
            % Se asegura que el límite inferior no sea negativo, ya que la distancia no puede serlo.
            app.LCL = max(0, app.refMean - 3 * app.refSigma);
            
            % Marcar modelo como entrenado
            app.modeloEntrenado = true;
            
           
            app.ModelStatusLabel.Text = sprintf('Modelo: ENTRENADO | UCL=%.2f | LCL=%.2f | μ=%.2f | σ=%.2f', ...
                app.UCL, app.LCL, app.refMean, app.refSigma);
            app.ModelStatusLabel.FontColor = [0 0.7 0];
            app.StartButton.Enable = 'on';
            
            % Visualizar distribución de distancias de entrenamiento 
            plot(app.UIAxes2, 1:length(distancias), distancias, 'bo-', 'LineWidth', 2);
            hold(app.UIAxes2, 'on');
            yline(app.UIAxes2, app.UCL, 'r--', 'UCL', 'LineWidth', 2);
            yline(app.UIAxes2, app.LCL, 'r--', 'LCL', 'LineWidth', 2);
            yline(app.UIAxes2, app.refMean, 'g--', 'Media', 'LineWidth', 1.5);
            hold(app.UIAxes2, 'off');
            title(app.UIAxes2, 'Distancias Euclidianas - Fase de Entrenamiento');
            xlabel(app.UIAxes2, 'Ventana');
            ylabel(app.UIAxes2, 'Distancia Euclidiana');
            legend(app.UIAxes2, 'Distancias', 'UCL (+3σ)', 'LCL (-3σ)', 'Media');
            grid(app.UIAxes2, 'on');
            
            fprintf('\n=== MODELO ENTRENADO EXITOSAMENTE (MÉTODO PROMEDIADO) ===\n');
            fprintf('Media: %.4f\n', app.refMean);
            fprintf('Sigma: %.4f\n', app.refSigma);
            fprintf('UCL (+3σ): %.4f\n', app.UCL);
            fprintf('LCL (-3σ): %.4f\n', app.LCL);
            fprintf('============================================================\n\n');
         end %end entrenar modelo%end entrenar modelo
        
         function procesarMuestraOnline(app)
            % FASE ONLINE: Procesa cada nueva muestra 
            
            if ~app.isRunning || ~app.modeloEntrenado
                return;
            end
            
            app.sampleCount = app.sampleCount + 1;
            
            % Simular lectura del sensor (con posibilidad de anomalías)
            t = app.sampleCount / app.Fs;
            humedad = 50 + 15*sin(2*pi*0.05*t) + 3*randn;
            
            % Inyectar anomalías ocasionalmente para pruebas
            if rand < 0.05 % 5% probabilidad de anomalía
                tipo_anomalia = randi(3);
                switch tipo_anomalia
                    case 1 % Bias
                        humedad = humedad + 30;
                        fprintf('[Muestra %d] Anomalía tipo BIAS inyectada\n', app.sampleCount);
                    case 2 % Drift
                        humedad = humedad * 1.5;
                        fprintf('[Muestra %d] Anomalía tipo DRIFT inyectada\n', app.sampleCount);
                    case 3 % Ruido excesivo
                        humedad = humedad + 20*randn;
                        fprintf('[Muestra %d] Anomalía tipo RUIDO inyectada\n', app.sampleCount);
                end
            end
            
            % Limitar al rango del sensor
            humedad = max(0, min(100, humedad));
            
            % Agregar al buffer
            app.bufferOnline = [app.bufferOnline, humedad];
            app.senalCompleta = [app.senalCompleta, humedad];
            
            % Mantener tamaño de visualización manejable
            if length(app.senalCompleta) > 200
                app.senalCompleta = app.senalCompleta(end-199:end);
            end
            
            % Actualizar gráfica de señal
            plot(app.UIAxes, app.senalCompleta, 'b-', 'LineWidth', 1.5);
            title(app.UIAxes, sprintf('Señal del Sensor - Muestra %d', app.sampleCount));
            xlabel(app.UIAxes, 'Muestras');
            ylabel(app.UIAxes, 'Humedad (%)');
            ylim(app.UIAxes, [0 100]);
            grid(app.UIAxes, 'on');
            
            % Análisis DWT cuando tengamos una ventana completa
            if length(app.bufferOnline) >= app.ventanaSize
                % Tomar ventana actual
                ventana_actual = app.bufferOnline(end-app.ventanaSize+1:end);
                
                % Aplicar DWT Haar
                [c_actual, l_actual] = wavedec(ventana_actual, 3, 'haar');
                
                % Calcular distancia con el modelo de referencia
                dist_actual = norm(c_actual - app.refVector);
                
                % Almacenar para visualización
                app.distancias = [app.distancias, dist_actual];
                app.tiempos = [app.tiempos, t];
                app.muestrasAnalizadas = app.muestrasAnalizadas + 1;
                
                % Mantener historial manejable
                if length(app.distancias) > 100
                    app.distancias = app.distancias(end-99:end);
                    app.tiempos = app.tiempos(end-99:end);
                end
                
                % Actualizar gráfica de control
                plot(app.UIAxes2, app.tiempos, app.distancias, 'b-', 'LineWidth', 2);
                hold(app.UIAxes2, 'on');
                
                % Dibujar límites de control
                if length(app.tiempos) > 1
                    x_lim = [app.tiempos(1), app.tiempos(end)];
                    plot(app.UIAxes2, x_lim, [app.UCL, app.UCL], 'r--', 'LineWidth', 2);
                    plot(app.UIAxes2, x_lim, [app.LCL, app.LCL], 'r--', 'LineWidth', 2);
                    plot(app.UIAxes2, x_lim, [app.refMean, app.refMean], 'g--', 'LineWidth', 1);
                end
                
                hold(app.UIAxes2, 'off');
                title(app.UIAxes2, 'Gráfico de Control - Distancias DWT');
                xlabel(app.UIAxes2, 'Tiempo (s)');
                ylabel(app.UIAxes2, 'Distancia Euclidiana');
                legend(app.UIAxes2, 'Distancia', 'UCL', 'LCL', 'Media', 'Location', 'best');
                grid(app.UIAxes2, 'on');
                
                % Mostrar coeficientes de detalle
                [d1,d2,d3] = detcoef(c_actual, l_actual, [1 2 3]);
                app.d1Label.Text = sprintf('D1: [%.2f, %.2f, %.2f, %.2f]', d1(1:min(4,end)));
                app.d2Label.Text = sprintf('D2: [%.2f, %.2f]', d2(1:min(2,end)));
                app.d3Label.Text = sprintf('D3: [%.2f]', d3(1));
                
                % DETECCIÓN DE ANOMALÍAS (Algoritmo 4 del paper)
                if dist_actual > app.UCL || dist_actual < app.LCL
                    % ANOMALÍA DETECTADA
                    app.IndicadorLamp.Color = [1 0 0]; % LED Rojo
                    app.EstadoLabel.Text = sprintf('ANOMALÍA (Dist=%.2f)', dist_actual);
                    app.EstadoLabel.FontColor = [1 0 0];
                    app.anomaliasDetectadas = app.anomaliasDetectadas + 1;
                    
                    fprintf('*** ANOMALÍA DETECTADA ***\n');
                    fprintf('Muestra: %d | Distancia: %.4f | UCL: %.4f | LCL: %.4f\n', ...
                        app.sampleCount, dist_actual, app.UCL, app.LCL);
                    fprintf('Total anomalías: %d/%d (%.1f%%)\n\n', ...
                        app.anomaliasDetectadas, app.muestrasAnalizadas, ...
                        100*app.anomaliasDetectadas/app.muestrasAnalizadas);
                else
                    % COMPORTAMIENTO NORMAL
                    app.IndicadorLamp.Color = [0 1 0]; % LED Verde
                    app.EstadoLabel.Text = sprintf('NORMAL (Dist=%.2f)', dist_actual);
                    app.EstadoLabel.FontColor = [0 0.7 0];
                end
                
                % Actualizar información
                app.InfoLabel.Text = sprintf('Muestras: %d | Anomalías: %d (%.1f%%)', ...
                    app.muestrasAnalizadas, app.anomaliasDetectadas, ...
                    100*app.anomaliasDetectadas/max(1,app.muestrasAnalizadas));
            end
        end


    end %end methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configuración inicial
            app.EstadoLabel.Text = 'SISTEMA DETENIDO';
            app.EstadoLabel.FontColor = [0.5 0.5 0.5];
            app.IndicadorLamp.Color = [0.5 0.5 0.5];
            
            app.ModelStatusLabel.Text = 'Modelo: NO ENTRENADO';
            app.ModelStatusLabel.FontColor = [1 0.5 0];
            
            app.InfoLabel.Text = 'Presione "Entrenar Modelo" para comenzar';
            app.StartButton.Enable = 'off';
            
            app.d1Label.Text = 'D1: [ - - - - ]';
            app.d2Label.Text = 'D2: [ - - ]';
            app.d3Label.Text = 'D3: [ - ]';
            
            % Configurar timer (7.5 segundos entre muestras = 8 muestras/minuto)
            app.timerObj = timer('ExecutionMode', 'fixedRate', ...
                                'Period', 60/app.Fs, ...
                                'TimerFcn', @(~,~)app.procesarMuestraOnline());
            
            fprintf('\n========================================\n');
            fprintf('SISTEMA DE DETECCIÓN DE ANOMALÍAS DWT\n');
            fprintf('Basado en: Wavelet-Based Computational Intelligence\n');
            fprintf('========================================\n\n');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            %no
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                 if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj);
                delete(app.timerObj);
            end
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
         if ~app.modeloEntrenado
                app.InfoLabel.Text = 'ERROR: Debe entrenar el modelo primero';
                app.InfoLabel.FontColor = [1 0 0];
                return;
         end
            
            if ~app.isRunning
                % INICIAR FASE ONLINE
                app.isRunning = true;
                app.sampleCount = 0;
                app.bufferOnline = [];
                app.distancias = [];
                app.tiempos = [];
                app.senalCompleta = [];
                app.anomaliasDetectadas = 0;
                app.muestrasAnalizadas = 0;
                
                start(app.timerObj);
                
                app.StartButton.Text = 'Detener Monitoreo';
                app.StartButton.BackgroundColor = [1 0.3 0.3];
                app.EstadoLabel.Text = 'MONITOREANDO...';
                app.EstadoLabel.FontColor = [0 0 1];
                
                fprintf('\n=== FASE ONLINE INICIADA ===\n');
                fprintf('Monitoreando sensor en tiempo real...\n\n');
            else
                % DETENER MONITOREO
                app.isRunning = false;
                stop(app.timerObj);
                
                app.StartButton.Text = 'Iniciar Monitoreo';
                app.StartButton.BackgroundColor = [0.3 1 0.3];
                app.EstadoLabel.Text = 'SISTEMA DETENIDO';
                app.EstadoLabel.FontColor = [0.5 0.5 0.5];
                app.IndicadorLamp.Color = [0.5 0.5 0.5];
                
                fprintf('\n=== MONITOREO DETENIDO ===\n');
                if app.muestrasAnalizadas > 0
                    fprintf('Resumen: %d anomalías en %d muestras (%.1f%%)\n\n', ...
                        app.anomaliasDetectadas, app.muestrasAnalizadas, ...
                        100*app.anomaliasDetectadas/app.muestrasAnalizadas);
                end
            end
      
           
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            % FASE OFFLINE: Entrenar modelo
            app.InfoLabel.Text = 'Generando datos de entrenamiento...';
            drawnow;
            
            % Generar datos offline
            app.generarDatosOffline();
            
            % Entrenar modelo
            app.InfoLabel.Text = 'Entrenando modelo...';
            drawnow;
            app.entrenarModelo();
            
            if app.modeloEntrenado
                app.InfoLabel.Text = 'Modelo entrenado exitosamente. Puede iniciar monitoreo.';
                app.InfoLabel.FontColor = [0 0.7 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal en tiempo real')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Coeficientes de detalle DWT-Haar')
            xlabel(app.UIAxes2, 'Tiempo (muestras)')
            ylabel(app.UIAxes2, 'Magnitud')
            zlabel(app.UIAxes2, 'Z')
            app.IndicadorLampLabel = uilabel(app.UIFigure);
            app.IndicadorLampLabel.Text = 'Indicador';
            app.IndicadorLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EstadoLabel = uilabel(app.UIFigure);
            app.EstadoLabel.Text = 'Estado';
            app.ModelStatusLabel = uilabel(app.UIFigure);
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Entrenar modelo';
            app.d1Label = uilabel(app.UIFigure);
            app.d1Label.Text = 'd1';
            app.d2Label = uilabel(app.UIFigure);
            app.d2Label.Text = 'd2';
            app.d3Label = uilabel(app.UIFigure);
            app.d3Label.Text = 'd2';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end